classdef GBManalizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenFlairMenu           matlab.ui.container.Menu
        DICOM_Flair             matlab.ui.container.Menu
        NifTI_Flair             matlab.ui.container.Menu
        OpenT2Menu              matlab.ui.container.Menu
        DICOM_T2                matlab.ui.container.Menu
        NifTI_T2                matlab.ui.container.Menu
        OpenT1Menu              matlab.ui.container.Menu
        DICOM_T1                matlab.ui.container.Menu
        NifTI_T1                matlab.ui.container.Menu
        OpenT1CMenu             matlab.ui.container.Menu
        DICOM_T1C               matlab.ui.container.Menu
        NifTI_T1C               matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        AnonimizeMenu           matlab.ui.container.Menu
        DisplaycontrolsPanel    matlab.ui.container.Panel
        InfoButton              matlab.ui.control.Button
        AdjcontrastButton       matlab.ui.control.Button
        FCheckBox_T1C           matlab.ui.control.CheckBox
        FCheckBox_T1            matlab.ui.control.CheckBox
        FCheckBox_T2            matlab.ui.control.CheckBox
        FCheckBox_Flair         matlab.ui.control.CheckBox
        MoveallLabel            matlab.ui.control.Label
        Spinner_View            matlab.ui.control.Spinner
        Volumesmm3Panel         matlab.ui.container.Panel
        CoreEditField           matlab.ui.control.NumericEditField
        CoreEditFieldLabel      matlab.ui.control.Label
        ETEditField             matlab.ui.control.NumericEditField
        ETEditFieldLabel        matlab.ui.control.Label
        NecrosisEditField       matlab.ui.control.NumericEditField
        NecrosisEditFieldLabel  matlab.ui.control.Label
        EdemaEditField          matlab.ui.control.NumericEditField
        EdemaEditFieldLabel     matlab.ui.control.Label
        SegmentationpanelPanel  matlab.ui.container.Panel
        PREPROCESSINGButton     matlab.ui.control.Button
        SEGMENTButton           matlab.ui.control.Button
        T1Label_2               matlab.ui.control.Label
        T1CLabel                matlab.ui.control.Label
        T2Label                 matlab.ui.control.Label
        FLAIRLabel              matlab.ui.control.Label
        Spinner_4               matlab.ui.control.Spinner
        Spinner_4Label          matlab.ui.control.Label
        Spinner_3               matlab.ui.control.Spinner
        Spinner_3Label          matlab.ui.control.Label
        Spinner_2               matlab.ui.control.Spinner
        Spinner_2Label          matlab.ui.control.Label
        Spinner_1               matlab.ui.control.Spinner
        Label                   matlab.ui.control.Label
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_1                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %Property % Description
        Flair;
        InfoF;
        structFlair;
        pathFlair;
        NImagF;
        Flair_Flag=0;
        Z_Flair;

        T2;
        InfoT2;
        structT2;
        pathT2;
        NImagT2;
        T2_Flag=0;
        Z_T2;

        T1;
        InfoT1;
        structT1;
        pathT1;
        NImagT1;
        T1_Flag=0;
        Z_T1;

        T1C;
        InfoT1C;
        structT1C;
        pathT1C;
        NImagT1C;
        T1C_Flag=0;
        Z_T1C;

        NumSeries=0;
        
        % Contraste
        C=128;
        W=256;

    end

    methods (Access = private)

     % Update All images   
        function UpdateImages(app,NumI)
            app.NImagF=NumI; app.Spinner_1.Value=NumI;
            app.NImagT2=NumI;app.Spinner_2.Value=NumI;
            app.NImagT1=NumI; app.Spinner_3.Value=NumI;
            app.NImagT1C=NumI; app.Spinner_4.Value=NumI;

            imagesc(app.UIAxes_1, app.Flair(:,:,round(app.NImagF)));
            imagesc(app.UIAxes_2, app.T2(:,:,round(app.NImagT2)));
            imagesc(app.UIAxes_3, app.T1(:,:,round(app.NImagT1)));
            imagesc(app.UIAxes_4, app.T1C(:,:,round(app.NImagT1C)));

        end
        
        function [MRI_dcm, Head_dcm, out_MRI, path_file_dcm] = load_images_dcm(app)
            
            [filename, pathname,filterindex] = uigetfile({'*.dcm','Image-files (*.dcm)'}, ... %;'*.*', 'All Files (*.*)'
                'Open MRI files','MultiSelect', 'on'); %'Imagen.dcm'
            %Essentials=1;
            if isequal(filterindex,0)
                error(' No file loaded')
            else
                [x,y,~]=size(filename);
                Filenames=repmat({pathname},y,x);
                Filenames=strcat(Filenames,filename');
                [out_MRI, path_file_dcm]=dcm_to_nifti(Filenames, pathname);
                Head_dcm=dicominfo(Filenames{1});
                
                path_file_dcm_t=erase(path_file_dcm,',1');
                
                MRI_dcm=niftiread(path_file_dcm_t);
                %Head_dcm=niftiinfo(path_file_dcm_t);
            end
            
        end
        
        function [MRI_nii, Head_nii, path_file_nii] = load_images_nii(app)
            [filename, pathname,filterindex] = uigetfile({'*.nii','Image-files (*.nii)'}, ... %;'*.*', 'All Files (*.*)'
                'Open MRI files'); %'Imagen.nii'
            %Essentials=1;
            if isequal(filterindex,0)
                error('No file loaded')
            else
                path_file_nii=[pathname filename];
                MRI_nii=niftiread(path_file_nii);
                Head_nii=niftiinfo(path_file_nii);
            end
        end
        
        function [im_nii, head_nii] = read_image_nii(app, path_im)
            
            im_nii=niftiread(erase(path_im,',1'));
            head_nii=niftiinfo(erase(path_im,',1'));
            
        end
        
        function showMRIInfo(app,info)
            fig = figure('Name', 'Info', 'NumberTitle', 'off', 'Position', [100, 100, 400, 300]);
            infoText = uicontrol('Style', 'edit', 'Position', [20, 80, 360, 200], 'HorizontalAlignment', 'left', 'FontName', 'Arial', 'FontSize', 10, 'Max', 2);

            infoStr = structInfoToString(app, info);

            set(infoText, 'String', infoStr);
        end
        
        function infoStr = structInfoToString(app,info)
            infoToShow = {
                'Filename', 'FileSize', 'Manufacturer','AcquisitionDate','Format','FormatVersion', 'Width', ...
                'Height', 'BitDepth', 'ColorType', ...
                'Modality', 'ImageType', 'SeriesDescription', 'ProtocolName','StudyComments', ...
                'PatientSex','PatientAge', 'BodyPartExamined', 'ScanningSequence', 'SequenceVariant', ...
                'ScanOptions', 'Rows','Columns', 'PixelSpacing', 'SmallestImagePixelValue', ...
                'LargestImagePixelValue', 'WindowCenter', 'WindowWidth', ...
                "Version", "Description", "ImageSize", "PixelDimensions", "Datatype", "BitsPerPixel", "SpaceUnits"
                };
            infoStr = '';

            for i = 1:length(infoToShow)
                fieldName = infoToShow{i};

                if isfield(info, fieldName)
                    fieldValue = info.(fieldName);

                    if strcmp(fieldName, 'PixelSpacing')
                        fieldValueStr = sprintf('%.4f\n%.4f', fieldValue(1), fieldValue(2));
                    elseif ischar(fieldValue)
                        fieldValueStr = fieldValue;
                    elseif isnumeric(fieldValue) || islogical(fieldValue)
                        fieldValueStr = num2str(fieldValue);
                    else
                        fieldValueStr = 'N/A';
                    end

                    infoStr = sprintf('%s%s: %s\n\n', infoStr, fieldName, fieldValueStr);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_1.Colormap = gray;%(256);
            axis(app.UIAxes_1, 'image');

            app.UIAxes_2.Visible = 'off';
            app.UIAxes_2.Colormap = gray;%(256);
            axis(app.UIAxes_2, 'image');

            app.UIAxes_3.Visible = 'off';
            app.UIAxes_3.Colormap = gray;%(256);
            axis(app.UIAxes_3, 'image');

            app.UIAxes_4.Visible = 'off';
            app.UIAxes_4.Colormap = gray;%(256);
            axis(app.UIAxes_4, 'image');

            app.Spinner_1.Enable='off';
            app.Spinner_2.Enable='off';
            app.Spinner_3.Enable='off';
            app.Spinner_4.Enable='off';
            
            app.SEGMENTButton.Enable='off';
            app.PREPROCESSINGButton.Enable='off';
        end

        % Value changing function: Spinner_1
        function Spinner_1ValueChanging(app, event)

            app.NImagF = event.Value;
            %app.NImagF = app.Spinner_1.Value;
            imagesc(app.UIAxes_1, app.Flair(:,:,round(app.NImagF)))

        end

        % Value changing function: Spinner_2
        function Spinner_2ValueChanging(app, event)
            app.NImagT2 = event.Value;
            imagesc(app.UIAxes_2, app.T2(:,:,round(app.NImagT2)))
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            app.NImagT1 = event.Value;
            imagesc(app.UIAxes_3, app.T1(:,:,round(app.NImagT1)))

        end

        % Value changing function: Spinner_4
        function Spinner_4ValueChanging(app, event)
            app.NImagT1C = event.Value;
            imagesc(app.UIAxes_4, app.T1C(:,:,round(app.NImagT1C)))

        end

        % Button pushed function: SEGMENTButton
        function SEGMENTButtonPushed(app, event)
            try
                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    %close all
                    [SegEnd,bwActivo,bwNecro,bwEdema]=SegmentationGBM3D(app.Flair,app.T2, app.T1C, app.T1 );
                    figure('Name','Final_Segmentation','NumberTitle','off'), imshow3D(SegEnd)

                    % Volumes
                    bwCore=bwActivo+bwNecro;
                    V_nec = regionprops3(bwNecro,"Volume");
                    V_actv = regionprops3(bwActivo,"Volume");
                    V_edema = regionprops3(bwEdema,"Volume");
                    V_core = regionprops3(bwCore,"Volume");

                    app.NecrosisEditField.Value=sum(V_nec.Volume);
                    app.ETEditField.Value=sum(V_actv.Volume);
                    app.EdemaEditField.Value=sum(V_edema.Volume);
                    app.CoreEditField.Value=sum(V_core.Volume);
                else
                    msgbox(["Error", "Check that all MRI modalities are loaded"])
                end
            catch ME
                close all
                msgbox([" Error: ", ME.identifier]);
                
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app)
            
        end

        % Value changing function: Spinner_View
        function Spinner_ViewValueChanging(app, event)
            if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                UpdateImages(app,event.Value)
            else
                msgbox(["Error", "Check number of MRI series"])
                app.Spinner_View.Value=1;
            end
            
        end

        % Button pushed function: AdjcontrastButton
        function AdjcontrastButtonPushed(app, event)
            try
                if app.FCheckBox_Flair.Value==1
                    %                 I_temp = app.Flair(:,:,app.NImagF);
                    %                 imagesc(app.UIAxes_1,I_temp)
                    imcontrast(app.UIAxes_1)
                elseif app.FCheckBox_T2.Value==1
                    %                 I_temp = app.T2(:,:,app.NImagT2);
                    %                 imagesc(app.UIAxes_2,I_temp)
                    imcontrast(app.UIAxes_2)
                elseif app.FCheckBox_T1.Value==1
                    %                 I_temp = app.T1(:,:,app.NImagT1);
                    %                 imagesc(app.UIAxes_3,I_temp)
                    imcontrast(app.UIAxes_3)
                elseif app.FCheckBox_T1C.Value==1
                    %                 I_temp = app.T1C(:,:,app.NImagT1C);
                    %                 imagesc(app.UIAxes_4,I_temp)
                    imcontrast(app.UIAxes_4)
                else
                    msgbox(["Error", "Mark only the desired MRI modality" ])
                end
            catch ME
                msgbox([" Error: ", ME.identifier]);
            end

        end

        % Button pushed function: PREPROCESSINGButton
        function PREPROCESSINGButtonPushed(app, event)
            try
                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]

                    % Reslice whit corr
                    fwb = waitbar(0,'Reslicing ...');
                    path_ref = 'w_s1999-09-24_11-26-122739-00001-00001-1.nii';
                    [filepath,~,~] = fileparts(which('reslice_with_corr'));
                    ref_r = strcat(filepath, '\', path_ref);
                    
                    [rT1_path]= reslice_with_corr(ref_r, app.pathT1);
                    
                    % Coorregister and reslice
                    waitbar(0.1,fwb,'T1 Corregister...');
                    [~, c_rT1_path]=correg_and_reslice(rT1_path, app.pathT1); %rT1_path definitive
                    ref_c = c_rT1_path;
                    
                    waitbar(0.2,fwb,'T2 Corregister...');
                    [~, c_rT2_path] = correg_and_reslice(ref_c, app.pathT2); %rT2_path
                    
                    waitbar(0.3,fwb,'Flair Corregister...');
                    [~, c_rF_path] = correg_and_reslice(ref_c, app.pathFlair); %rF_path
                    
                    waitbar(0.4,fwb,'T1C Corregister...');
                    [~, c_rT1C_path] = correg_and_reslice(ref_c, app.pathT1C); %rT1C_path
                    
                    waitbar(0.5,fwb,'Loading Nifti files ...');
                    app.T1=niftiread(erase(c_rT1_path,',1'));
                    app.T2=niftiread(erase(c_rT2_path,',1'));
                    app.Flair=niftiread(erase(c_rF_path,',1'));
                    app.T1C=niftiread(erase(c_rT1C_path,',1'));

                    % Using skull striping function
                    app.pathT1=c_rT1_path;
                    app.pathT2=c_rT2_path;
                    app.pathFlair=c_rF_path;
                    app.pathT1C=c_rT1C_path;
                    
                    waitbar(0.6,fwb,'Skull striping ...');
                    [filepath,~,~] = fileparts(which('skull_striping'));
                    path_ref = strcat(filepath, '\TPM.nii');
                    
                    [BW_mask,~]=skull_striping(app.pathT1, app.pathT2,app.pathFlair,app.pathT1C, path_ref);
                    app.Flair(BW_mask==0)=0;
                    app.T1C(BW_mask==0)=0;
                    app.T1(BW_mask==0)=0;
                    app.T2(BW_mask==0)=0;
                    
                    UpdateImages(app,1)
                    app.Spinner_View.Value=1;
                    app.Spinner_1.Value=1;
                    app.Spinner_2.Value=1;
                    app.Spinner_3.Value=1;
                    app.Spinner_4.Value=1;
                    [~,~,app.Z_Flair]=size(app.Flair);
                    [~,~,app.Z_T2]=size(app.T2);
                    [~,~,app.Z_T1]=size(app.T1);
                    [~,~,app.Z_T1C]=size(app.T1C);

                    Z=min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C]);
                    app.Spinner_View.Limits=[1 Z];
                    app.Spinner_1.Limits=[1 app.Z_Flair];
                    app.Spinner_2.Limits=[1 app.Z_T2];
                    app.Spinner_3.Limits=[1 app.Z_T1];
                    app.Spinner_4.Limits=[1 app.Z_T1C];
                    waitbar(1,fwb,'Finishing');
                    close(fwb);
                    
                else
                    msgbox(["Error", "All MRI must be loaded" ])
                end
            catch ME
                msgbox([" Error: ", ME.identifier]);
                close(fwb);
            end
        end

        % Menu selected function: DICOM_Flair
        function DICOM_FlairSelected(app, event)
            try
                [app.Flair, app.InfoF, app.structFlair, app.pathFlair]=load_images_dcm(app);
                %axis(app.UIAxes_1, 'image');
                imagesc(app.UIAxes_1, app.Flair(:,:,1))
                [~,~,app.Z_Flair]=size(app.Flair);
                app.Spinner_1.Limits=[1 app.Z_Flair];
                app.Spinner_1.Enable='on';
                app.Spinner_1.Value=1;

                app.Flair_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end
            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_1.Enable='off';
                app.Flair_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end

        end

        % Menu selected function: NifTI_Flair
        function NifTI_FlairSelected(app, event)
            try
                [app.Flair, app.InfoF, app.pathFlair]=load_images_nii(app);
                %axis(app.UIAxes_1, 'image');
                imagesc(app.UIAxes_1, app.Flair(:,:,1))
                [~,~,app.Z_Flair]=size(app.Flair);
                app.Spinner_1.Limits=[1 app.Z_Flair];
                app.Spinner_1.Enable='on';
                app.Spinner_1.Value=1;

                app.Flair_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end
            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_1.Enable='off';
                app.Flair_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end
        end

        % Menu selected function: DICOM_T2
        function DICOM_T2Selected(app, event)
            try
                [app.T2, app.InfoT2, app.structT2, app.pathT2]=load_images_dcm(app);
                imagesc(app.UIAxes_2, app.T2(:,:,1))
                [~,~,app.Z_T2]=size(app.T2);
                app.Spinner_2.Limits=[1 app.Z_T2];
                app.Spinner_2.Enable='on';
                app.T2_Flag=1;
                app.Spinner_2.Value=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_2.Enable='off';
                app.T2_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end
        
        end

        % Menu selected function: NifTI_T2
        function NifTI_T2MenuSelected(app, event)
            try
                [app.T2, app.InfoT2, app.pathT2]=load_images_nii(app);
                imagesc(app.UIAxes_2, app.T2(:,:,1))
                [~,~,app.Z_T2]=size(app.T2);
                app.Spinner_2.Limits=[1 app.Z_T2];
                app.Spinner_2.Enable='on';
                app.T2_Flag=1;
                app.Spinner_2.Value=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_2.Enable='off';
                app.T2_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end
        end

        % Menu selected function: DICOM_T1
        function DICOM_T1MenuSelected(app, event)
            try
                [app.T1, app.InfoT1, app.structT1, app.pathT1]=load_images_dcm(app);
                imagesc(app.UIAxes_3, app.T1(:,:,1))
                [~,~,app.Z_T1]=size(app.T1);
                app.Spinner_3.Limits=[1 app.Z_T1];
                app.Spinner_3.Enable='on';
                app.Spinner_3.Value=1;

                app.T1_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_3.Enable='off';
                app.T1_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end
        end

        % Menu selected function: NifTI_T1
        function NifTI_T1MenuSelected(app, event)
            try
                [app.T1, app.InfoT1, app.pathT1]=load_images_nii(app);
                imagesc(app.UIAxes_3, app.T1(:,:,1))
                [~,~,app.Z_T1]=size(app.T1);
                app.Spinner_3.Limits=[1 app.Z_T1];
                app.Spinner_3.Enable='on';
                app.Spinner_3.Value=1;

                app.T1_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_3.Enable='off';
                app.T1_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end
        end

        % Menu selected function: DICOM_T1C
        function DICOM_T1CMenuSelected(app, event)
           try
                [app.T1C, app.InfoT1C, app.structT1C, app.pathT1C]=load_images_dcm(app);
                imagesc(app.UIAxes_4, app.T1C(:,:,1))
                [~,~,app.Z_T1C]=size(app.T1C);
                app.Spinner_4.Limits=[1 app.Z_T1C];
                app.Spinner_4.Enable='on';
                app.Spinner_4.Value=1;

                app.T1C_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_2.Enable='off';
                app.T1C_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end 
        end

        % Menu selected function: NifTI_T1C
        function NifTI_T1CMenuSelected(app, event)
            try
                [app.T1C, app.InfoT1C, app.pathT1C]=load_images_nii(app);
                imagesc(app.UIAxes_4, app.T1C(:,:,1))
                [~,~,app.Z_T1C]=size(app.T1C);
                app.Spinner_4.Limits=[1 app.Z_T1C];
                app.Spinner_4.Enable='on';
                app.Spinner_4.Value=1;

                app.T1C_Flag=1;

                if [app.Flair_Flag app.T2_Flag app.T1_Flag app.T1C_Flag] == [1 1 1 1]
                    app.SEGMENTButton.Enable='on';
                    app.PREPROCESSINGButton.Enable='on';
                    app.Spinner_View.Limits=[1 min([app.Z_Flair app.Z_T2 app.Z_T1 app.Z_T1C])];
                end

            catch ME
                msgbox([" Error: ", ME.identifier]);
                app.Spinner_2.Enable='off';
                app.T1C_Flag=0;
                app.SEGMENTButton.Enable='off';
                app.PREPROCESSINGButton.Enable='off';
            end 
        end

        % Menu selected function: AnonimizeMenu
        function AnonimizeMenuSelected(app, event)
            % Obtener el directorio de entrada utilizando uigetdir
            inputDirectory = uigetdir('Selecciona el directorio de entrada con archivos DICOM');

            if inputDirectory == 0
                disp('No se seleccionó ningún directorio. El proceso ha sido cancelado.');
                return;
            end

            % Crear la carpeta "Anonimized" dentro del directorio de entrada
            outputDirectory = fullfile(inputDirectory, 'Anonimized');
            if ~exist(outputDirectory, 'dir')
                mkdir(outputDirectory);
            end

            % Obtener la lista de archivos DICOM en el directorio de entrada
            dcmFiles = dir(fullfile(inputDirectory, '*.dcm'));

            % Definir los campos a mantener en la anonimización
            fieldsToKeep = {'PatientAge', 'PatientSex','SeriesDescription', 'ProtocolName','StudyDescription', 'PixelDimensions', 'ImageSize'};
            values.StudyInstanceUID = dicomuid;
            values.SeriesInstanceUID = dicomuid;
 
            % Iterar a través de los archivos DICOM y anonimizarlos
            fwb = waitbar(0,'Start anonimization ...');
            num_of_files=numel(dcmFiles);
            for i = 1:num_of_files
                dcmPath = fullfile(inputDirectory, dcmFiles(i).name);

                % Anonimizar el archivo DICOM utilizando dicomanon y mantener campos específicos
                dicomanon(dcmPath, fullfile(outputDirectory, ['anon_', dcmFiles(i).name]), ...
                   'keep', fieldsToKeep, 'update', values);
                waitbar(i/num_of_files,fwb,sprintf('File %d de %d anonimizated and saved.', i, numel(dcmFiles)));
                fprintf('Archivo %d de %d anonimizado y guardado.\n', i, numel(dcmFiles));
            end
            waitbar(1,fwb,sprintf('%d de %d anonimizated files.', i, numel(dcmFiles)));
            disp('Proceso completado.');
            %close(fwb)


        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)

            try
                if app.FCheckBox_Flair.Value==1
                    showMRIInfo(app, app.InfoF);
                elseif app.FCheckBox_T2.Value==1
                    showMRIInfo(app, app.InfoT2);
                elseif app.FCheckBox_T1.Value==1
                    showMRIInfo(app, app.InfoT1);
                elseif app.FCheckBox_T1C.Value==1
                    showMRIInfo(app, app.InfoT1C);
                else
                    msgbox(["Error", "Mark only the desired MRI modality to show info" ])
                end
            catch ME
                msgbox([" Error: ", ME.identifier]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenFlairMenu = uimenu(app.FileMenu);
            app.OpenFlairMenu.Text = 'Open Flair';
            app.DICOM_Flair = uimenu(app.OpenFlairMenu);
            app.DICOM_Flair.MenuSelectedFcn = createCallbackFcn(app, @DICOM_FlairSelected, true);
            app.DICOM_Flair.Text = 'DICOM';
            app.NifTI_Flair = uimenu(app.OpenFlairMenu);
            app.NifTI_Flair.MenuSelectedFcn = createCallbackFcn(app, @NifTI_FlairSelected, true);
            app.NifTI_Flair.Text = 'NifTI';
            app.OpenT2Menu = uimenu(app.FileMenu);
            app.OpenT2Menu.Text = 'Open T2';
            app.DICOM_T2 = uimenu(app.OpenT2Menu);
            app.DICOM_T2.MenuSelectedFcn = createCallbackFcn(app, @DICOM_T2Selected, true);
            app.DICOM_T2.Text = 'DICOM';
            app.NifTI_T2 = uimenu(app.OpenT2Menu);
            app.NifTI_T2.MenuSelectedFcn = createCallbackFcn(app, @NifTI_T2MenuSelected, true);
            app.NifTI_T2.Text = 'NifTI';
            app.OpenT1Menu = uimenu(app.FileMenu);
            app.OpenT1Menu.Text = 'Open T1';
            app.DICOM_T1 = uimenu(app.OpenT1Menu);
            app.DICOM_T1.MenuSelectedFcn = createCallbackFcn(app, @DICOM_T1MenuSelected, true);
            app.DICOM_T1.Text = 'DICOM';
            app.NifTI_T1 = uimenu(app.OpenT1Menu);
            app.NifTI_T1.MenuSelectedFcn = createCallbackFcn(app, @NifTI_T1MenuSelected, true);
            app.NifTI_T1.Text = 'NifTI';
            app.OpenT1CMenu = uimenu(app.FileMenu);
            app.OpenT1CMenu.Text = 'Open T1C';
            app.DICOM_T1C = uimenu(app.OpenT1CMenu);
            app.DICOM_T1C.MenuSelectedFcn = createCallbackFcn(app, @DICOM_T1CMenuSelected, true);
            app.DICOM_T1C.Text = 'DICOM';
            app.NifTI_T1C = uimenu(app.OpenT1CMenu);
            app.NifTI_T1C.MenuSelectedFcn = createCallbackFcn(app, @NifTI_T1CMenuSelected, true);
            app.NifTI_T1C.Text = 'NifTI';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.AnonimizeMenu = uimenu(app.ToolsMenu);
            app.AnonimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnonimizeMenuSelected, true);
            app.AnonimizeMenu.Text = 'Anonimize';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Spinner_1 = uispinner(app.UIFigure);
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1ValueChanging, true);
            app.Spinner_1.Limits = [1 155];
            app.Spinner_1.RoundFractionalValues = 'on';
            app.Spinner_1.Value = 1;
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = '';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2ValueChanging, true);
            app.Spinner_2.Limits = [1 155];
            app.Spinner_2.RoundFractionalValues = 'on';
            app.Spinner_2.Value = 1;
            app.Spinner_3Label = uilabel(app.UIFigure);
            app.Spinner_3Label.Text = '';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [1 155];
            app.Spinner_3.RoundFractionalValues = 'on';
            app.Spinner_3.Value = 1;
            app.Spinner_4Label = uilabel(app.UIFigure);
            app.Spinner_4Label.Text = '';
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4ValueChanging, true);
            app.Spinner_4.Limits = [1 155];
            app.Spinner_4.RoundFractionalValues = 'on';
            app.Spinner_4.Value = 1;
            app.FLAIRLabel = uilabel(app.UIFigure);
            app.FLAIRLabel.Text = 'FLAIR';
            app.T2Label = uilabel(app.UIFigure);
            app.T2Label.Text = 'T2';
            app.T1CLabel = uilabel(app.UIFigure);
            app.T1CLabel.Text = 'T1C';
            app.T1Label_2 = uilabel(app.UIFigure);
            app.T1Label_2.Text = 'T1';
            app.SegmentationpanelPanel = uipanel(app.UIFigure);
            app.SegmentationpanelPanel.Title = 'Segmentation panel';
            app.SEGMENTButton = uibutton(app.SegmentationpanelPanel, 'push');
            app.SEGMENTButton.ButtonPushedFcn = createCallbackFcn(app, @SEGMENTButtonPushed, true);
            app.SEGMENTButton.Text = 'SEGMENT';
            app.PREPROCESSINGButton = uibutton(app.SegmentationpanelPanel, 'push');
            app.PREPROCESSINGButton.ButtonPushedFcn = createCallbackFcn(app, @PREPROCESSINGButtonPushed, true);
            app.PREPROCESSINGButton.Text = 'PREPROCESSING';
            app.Volumesmm3Panel = uipanel(app.UIFigure);
            app.Volumesmm3Panel.Title = 'Volumes mm3';
            app.EdemaEditFieldLabel = uilabel(app.Volumesmm3Panel);
            app.EdemaEditFieldLabel.Text = 'Edema';
            app.EdemaEditField = uieditfield(app.Volumesmm3Panel, 'numeric');
            app.EdemaEditField.Editable = 'off';
            app.NecrosisEditFieldLabel = uilabel(app.Volumesmm3Panel);
            app.NecrosisEditFieldLabel.Text = 'Necrosis';
            app.NecrosisEditField = uieditfield(app.Volumesmm3Panel, 'numeric');
            app.NecrosisEditField.Editable = 'off';
            app.ETEditFieldLabel = uilabel(app.Volumesmm3Panel);
            app.ETEditFieldLabel.Text = 'ET';
            app.ETEditField = uieditfield(app.Volumesmm3Panel, 'numeric');
            app.ETEditField.Editable = 'off';
            app.CoreEditFieldLabel = uilabel(app.Volumesmm3Panel);
            app.CoreEditFieldLabel.Text = 'Core';
            app.CoreEditField = uieditfield(app.Volumesmm3Panel, 'numeric');
            app.CoreEditField.Editable = 'off';
            app.DisplaycontrolsPanel = uipanel(app.UIFigure);
            app.DisplaycontrolsPanel.Title = 'Display controls';
            app.Spinner_View = uispinner(app.DisplaycontrolsPanel);
            app.Spinner_View.ValueChangingFcn = createCallbackFcn(app, @Spinner_ViewValueChanging, true);
            app.MoveallLabel = uilabel(app.DisplaycontrolsPanel);
            app.MoveallLabel.Text = 'Move all';
            app.FCheckBox_Flair = uicheckbox(app.DisplaycontrolsPanel);
            app.FCheckBox_Flair.Text = 'F';
            app.FCheckBox_T2 = uicheckbox(app.DisplaycontrolsPanel);
            app.FCheckBox_T2.Text = 'T2';
            app.FCheckBox_T1 = uicheckbox(app.DisplaycontrolsPanel);
            app.FCheckBox_T1.Text = 'T1';
            app.FCheckBox_T1C = uicheckbox(app.DisplaycontrolsPanel);
            app.FCheckBox_T1C.Text = 'T1C';
            app.AdjcontrastButton = uibutton(app.DisplaycontrolsPanel, 'push');
            app.AdjcontrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjcontrastButtonPushed, true);
            app.AdjcontrastButton.Text = 'Adj. contrast';
            app.InfoButton = uibutton(app.DisplaycontrolsPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GBManalizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end